classdef GUIBiomecanica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Menu         matlab.ui.container.Menu
        Menu2        matlab.ui.container.Menu
        axsVideo     matlab.ui.control.UIAxes
        SwitchLabel  matlab.ui.control.Label
        Switch       matlab.ui.control.Switch
        axsGrafica   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            global depthVid
            value = app.Switch.Value;
            if strcmp(value,'On')
                close all;
                clc;
                imaqreset; %Resetea el objeto de imagenes
                
                %Video de entra, sensor de profundidad
                depthVid = videoinput('kinect',2);
                
                %obtiene las propiedades del video de entrada
                depthSource = getselectedsource(depthVid);
                depthSource.EnableBodyTracking = 'on'; %Enciende el seguimiendo del puntos
                
                %confuguracion del disparador
                triggerconfig(depthVid,'manual');
                depthVid.FramesPerTrigger = 1;
                depthVid.TriggerRepeat = inf;
                %set(getselectedsource(depthVid));
                
                %Inicio del video de profundidad
                start(depthVid);
                himg = figure; %crea un objeto figure
                
                while ishandle(himg)
                    trigger(depthVid);
                    [depthMap,~,depthMetaData] = getdata(depthVid);
                    imshow(depthMap,[0 4096],'Parent',app.axsGrafica);
                    imshow(depthMap,[0 4096],'Parent',app.axsVideo);
                    %verifica si la suma del seguimiento del cuerpo es mayor a cero
                    if sum(depthMetaData.IsBodyTracked)>0
                        skeletonJoints = depthMetaData.DepthJointIndices(:,:,depthMetaData.IsBodyTracked);
                        hold on;
                        plot(app.axsGrafica,skeletonJoints(:,1), skeletonJoints(:,2),'*');
                        hold off;
                        %else
                    end
                end
                
                stop(depthVid);
                
            else
                close all;
            end%end else
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.axsVideo = uiaxes(app.UIFigure);
            title(app.axsVideo, '')
            xlabel(app.axsVideo, '')
            ylabel(app.axsVideo, '')
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.axsGrafica = uiaxes(app.UIFigure);
            title(app.axsGrafica, {'Movimiento cuerpo'; ''})
            xlabel(app.axsGrafica, '')
            ylabel(app.axsGrafica, '')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIBiomecanica

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end